classdef ActiveContoursApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                      matlab.ui.Figure
        ViewMenu                        matlab.ui.container.Menu
        ImageToShowMenu                 matlab.ui.container.Menu
        ColoredMenu                     matlab.ui.container.Menu
        GrayMenu                        matlab.ui.container.Menu
        OpenSeperateImageWindowMenu     matlab.ui.container.Menu
        RecordMenu                      matlab.ui.container.Menu
        StartRecordingMenu              matlab.ui.container.Menu
        PauseRecordingMenu              matlab.ui.container.Menu
        StopRecordingMenu               matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        CreditsMenu                     matlab.ui.container.Menu
        UIAxes                          matlab.ui.control.UIAxes
        ImageWindowButton               matlab.ui.control.StateButton
        StartButton                     matlab.ui.control.Button
        ProgressBarAxes                 matlab.ui.control.UIAxes
        ProgressBarText                 matlab.ui.control.Label
        MaskCoverPercentageLabel        matlab.ui.control.Label
        MaskCoverPercentageEditField    matlab.ui.control.NumericEditField
        QuestionMark                    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        ChooseImageTab                  matlab.ui.container.Tab
        ImageFromlistListBoxLabel       matlab.ui.control.Label
        ImageFromlist                   matlab.ui.control.ListBox
        ChoosefromfileButton            matlab.ui.control.Button
        FileTree                        matlab.ui.container.Tree
        ImageTypeDropDown_4Label        matlab.ui.control.Label
        ImageTypeDropDown_4             matlab.ui.control.DropDown
        ImageManipulationsTab           matlab.ui.container.Tab
        HistogramEqualizationCheckBox   matlab.ui.control.CheckBox
        ResolutionEditField             matlab.ui.control.NumericEditField
        BlurringCheckBox                matlab.ui.control.CheckBox
        ResolutionLabel                 matlab.ui.control.Label
        ResolutionSlider                matlab.ui.control.Slider
        CropButton                      matlab.ui.control.Button
        ImageTypeDropDown_3Label        matlab.ui.control.Label
        ImageTypeDropDown_3             matlab.ui.control.DropDown
        BackgroundSubstractionRadiusEditFieldLabel  matlab.ui.control.Label
        BackgroundSubstractionRadiusEditField  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        ImageSegmentationTab            matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        MATLABsSnakesTab                matlab.ui.container.Tab
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        RegionButton                    matlab.ui.control.RadioButton
        EdgeButton                      matlab.ui.control.RadioButton
        SmoothFactorEditFieldLabel      matlab.ui.control.Label
        SmoothFactorEditField           matlab.ui.control.NumericEditField
        ContractionBiasEditFieldLabel   matlab.ui.control.Label
        ContractionBiasEditField        matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        IterationsPerFrameEditFieldLabel  matlab.ui.control.Label
        IterationsPerFrameEditField     matlab.ui.control.NumericEditField
        MasksRecombinationCheckBox      matlab.ui.control.CheckBox
        WatershedTab                    matlab.ui.container.Tab
        ToleranceEditFieldLabel         matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        AdaptiveThresholdTab            matlab.ui.container.Tab
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        DiskRadiusEditFieldLabel        matlab.ui.control.Label
        DiskRadiusEditField             matlab.ui.control.NumericEditField
        UpperThresholdEditFieldLabel    matlab.ui.control.Label
        UpperThresholdEditField         matlab.ui.control.NumericEditField
        LowerThresholdEditField_2Label  matlab.ui.control.Label
        LowerThresholdEditField         matlab.ui.control.NumericEditField
        SolidityFilterLabel             matlab.ui.control.Label
        MeanorMedianButtonGroup         matlab.ui.container.ButtonGroup
        MeanButton                      matlab.ui.control.RadioButton
        MedianButton                    matlab.ui.control.RadioButton
        CannyThresholdingFusionTab      matlab.ui.container.Tab
        ROIPanel                        matlab.ui.container.Panel
        AddManuallyButton               matlab.ui.control.Button
        AddAutoButton                   matlab.ui.control.Button
        RemoveAllButton                 matlab.ui.control.Button
        ShrinkAllButton                 matlab.ui.control.Button
        RemoveManuallyButton            matlab.ui.control.Button
        FromFileButton                  matlab.ui.control.Button
        ImageTypeDropDown_5Label        matlab.ui.control.Label
        ImageTypeDropDown_5             matlab.ui.control.DropDown
        RecordingLampLabel              matlab.ui.control.Label
        RecordingLamp                   matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        Paths ; % Class of all possible paths and images
        SegmentAlgo ; % Class to manage the parameters and execution of the Segmentation Algorithm         
        ImagesManager ;  % Class to controls the Behaviour, Manipulations, Additions and Acquisition  of Images
        WindowsManager ; % Manges and controls all open apps and windows
        ApearanceDefaultVals = struct('DefaultButtonColor' , [0.96, 0.96,0.96] , ...
                                      'ActiveButtonColor'  , [0.5 , 0.5 , 0  ] , ...
                                      'WaitingButtonColor' , [0.2 , 0.6 , 1  ] ,...
                                      'MaskColor'          , struct("ColoredImage" , [0.7   , 0   , 0.2  ] , ...
                                                                    "GrayImage"    , [0.8   , 0.1 , 0.1  ]   )...
                                   )
                                                                       
    end
    
    properties (Access = public)
        EmbeddedProgressBar
        pressOnScreenState = "idle";
    end
    
    methods (Access = private)
        
        function [] = read_user_chosen_image(app)
            chosen_image_str = app.ImageFromlist.Value;
            chosen_image_path = app.Paths.AvailablePictures.(chosen_image_str);
            Im = imread(chosen_image_path);
            app.ImagesManager.set_original_image(Im);           
        end % read_user_chosen_image


        function [] = wrong_folder_msg(app , folder_that_should_exist)
            ErrorMsg = "App Loading Error: App should run on its main folder with subfolders:";
            for i = 1 : length(folder_that_should_exist)
                ErrorMsg = ErrorMsg + newline + "    - " + folder_that_should_exist(i);
            end
            closeFunc = @(h,e)  delete(app);
            uialert(app.MainWindow,ErrorMsg,"Wrong Loading Path" , 'CloseFcn', closeFunc );
            error(ErrorMsg);   
        end
        
        function [] = update_resolution(app,value)
            value = max(min(100,value),0.001); % don't let it be 0 or over 100
            % Update app's visual buttons:
            app.ResolutionSlider.Value = value; % update ScrollerBar
            app.ResolutionEditField.Value = value; % update Precise Value            
            % set image Config:
            app.ImagesManager.set("Resolution" , value);
            % act according to config:
            app.ImagesManager.update_images_from_OriginalImage();
            app.ImagesManager.show_image();
        end
        
        function [] = set_default_values(app , ImageType)
            arguments
                app ActiveContoursApp
                ImageType string = "Unknown"
            end
            
            %Available Algorithms and Images:
            app.ImageFromlist.Items = app.Paths.AvailablePictures_string_cell_array();
            %Default Parameters:
            Params = default_params(ImageType);
            
            % Tabs Group:
            switch Params.General.ChosenAlgorithm
                case AvailableAlgorithms.MatlabBuiltIn
                    app.TabGroup.SelectedTab = app.MATLABsSnakesTab;
                case AvailableAlgorithms.Watershed
                    app.TabGroup.SelectedTab = app.WatershedTab ;
                case AvailableAlgorithms.AdaptiveThreshold
                    app.TabGroup.SelectedTab = app.AdaptiveThresholdTab;                    
                case AvailableAlgorithms.CannyThresholdingFusion
                    app.TabGroup.SelectedTab = app.CannyThresholdingFusionTab; 
                otherwise
                    error("Unkown Algorithm");
            end
            
            %% Matlab-BuiltIn:
            % ===============
            %method:
            switch Params.MatlabBuiltIn.Method
                case AvailableAlgorithms.MatlabBuiltInMethods.Edge
                    app.MethodButtonGroup.SelectedObject = app.EdgeButton;
                case AvailableAlgorithms.MatlabBuiltInMethods.Region
                    app.MethodButtonGroup.SelectedObject = app.RegionButton;
                otherwise
                    error("Unknown Method"); 
            end          
            %Contraction Bias:
            app.ContractionBiasEditField.Value = Params.MatlabBuiltIn.ContractionBias;
            %Smooth Factor:
            app.SmoothFactorEditField.Value = Params.MatlabBuiltIn.SmoothFactor;
            %Max iterations:
            app.MaxIterationsEditField.Value = Params.MatlabBuiltIn.MaxNumIteration;
            %Iteration per Frame:
            app.IterationsPerFrameEditField.Value = Params.MatlabBuiltIn.IterationsPerFrame;
            % MasksRecombination:
            app.MasksRecombinationCheckBox.Value = Params.MatlabBuiltIn.MasksRecombination;
            
            %% Watershed algo:
            % ===============
            %tolerance:
            app.ToleranceEditField.Value = Params.WaterShed.Tolerance;
            
            %% Adaptive Threshold:
            % ==================
            %Window size:
            app.WindowSizeEditField.Value = Params.AdaptiveThreshold.WindowSize;
            % Mean or Median
            if Params.AdaptiveThreshold.MeanOrMedian == 0
                app.MeanorMedianButtonGroup.SelectedObject = app.MeanButton;
            elseif Params.AdaptiveThreshold.MeanOrMedian == 1
                app.MeanorMedianButtonGroup.SelectedObject = app.MedianButton;
            else
                error("Unkown MeanOrMedian State" + string(Params.AdaptiveThreshold.MeanOrMedian) );
            end
            %Threshold:
            app.ThresholdEditField.Value = Params.AdaptiveThreshold.Threshold;
            % Disk Size:
            app.DiskRadiusEditField.Value = Params.AdaptiveThreshold.DistRadius;
            %Solidity:
            app.UpperThresholdEditField.Value = Params.AdaptiveThreshold.SolidityUpperThresh;
            app.LowerThresholdEditField.Value = Params.AdaptiveThreshold.SolidityLowerThresh;
            
            % Credits:
            app.QuestionMark.Tooltip = credits_string();


        end % set_default_values
        
        function [] = set(app, name , value)
            arguments
                app   ActiveContoursApp
                name  string
                value 
            end
            
            switch lower(name)
                case "imagetype"
                    ImageTypeStr = string(value);
                    app.ImageTypeDropDown_4.Value = ImageTypeStr;
                    app.ImageTypeDropDown_3.Value = ImageTypeStr;
                    app.ImageTypeDropDown_5.Value = ImageTypeStr;
                    app.set_default_values(value)
                case "recording"
                    if     value == "start"
                        app.StartRecordingMenu.Enable = 'off';                        
                        app.StopRecordingMenu.Enable  = 'on';
                        app.PauseRecordingMenu.Enable = 'on';
                        app.RecordingLamp.Visible = 'on';
                        app.RecordingLampLabel.Visible = "on";
                        app.RecordingLamp.Enable  = 'on';
                    elseif value == "stop"
                        app.StartRecordingMenu.Enable = 'on';
                        app.StopRecordingMenu.Enable  = 'off';                       
                        app.PauseRecordingMenu.Enable = 'off';
                        app.RecordingLamp.Visible = 'off';
                        app.RecordingLampLabel.Visible = "off";
                        app.RecordingLamp.Enable  = 'off';
                    elseif value == "pause"
                        app.StartRecordingMenu.Enable = 'on';
                        app.StopRecordingMenu.Enable  = 'on';                       
                        app.PauseRecordingMenu.Enable = 'off';
                        app.RecordingLamp.Visible = 'on';
                        app.RecordingLampLabel.Visible = "on";
                        app.RecordingLamp.Enable  = 'off';
                    else
                        error(string(value)+" not supproted for set recording");
                    end
                otherwise
                    error("Not a supported name");
            end
   
        end % set
        
        function [] = set_FileTree(app)
            DirTree = app.Paths.DirectoryTree;    
            AppTree = app.FileTree;
           
            subDirFields = fields( DirTree.subDirectories );
            for i = 1 : length(subDirFields)
                Field = subDirFields{i};
                subDirTree = DirTree.(Field);
                subAppTree = uitreenode(AppTree);
                set_FileTree_recursive(app , subDirTree , subAppTree);
            end
            
                    
        end % set_TreeDir
        
        function [] = set_FileTree_recursive(app , DirTree , AppTree)
            AppTree.Text = DirTree.Name;
            
            % Sub Directories:
            subDirFields = fields( DirTree.subDirectories );
            for i = 1 : length(subDirFields)
                Field = subDirFields{i};
                subDirTree = DirTree.(Field);
                subAppTree = uitreenode(AppTree);
                set_FileTree_recursive(app , subDirTree , subAppTree);
                
            end
            
            % Image
            imagesFields = fields( DirTree.Images );
            for i = 1 : length(imagesFields)
                Field = imagesFields{i};
                ImageStruct = DirTree.Images.(Field);
                edgeNode = uitreenode(AppTree);
                edgeNode.Text = ImageStruct.Name;
                edgeNode.NodeData = struct("Path" , ImageStruct.Path , ...
                                           "Type" , ImageStruct.Type);
                edgeNode.Tag = "ImageTreeNode";
            end
            
            
        end % set_FileTree_recursive


    end %private
    
    
    methods (Access = public) 
        
        function [] = show_image(app , Im )
            %Update figures:
            imshow( Im , 'Parent' , app.UIAxes);
            app.UIAxes.XLabel.Visible = "off";
            app.UIAxes.YLabel.Visible = "off";
            app.UIAxes.Title.Visible  = "off";
        end
        
        
        function [] = set_algoInProgress( app , on_off_str )
            switch lower(string(on_off_str))
                case "on"
                    app.StartButton.Text = "Stop";
                case "off"
                    app.StartButton.Text = "Start";
                otherwise
                    error("Wrong string as input");
            end % switch
        end
    end %public
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App folder:
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                disp(status);
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = pwd;
            end           
            % check correct path and add to MATLAB'S paths:
            currentDir = string(currentDir);
            [currentDir] = assert_correct_app_folder(app, currentDir);
            
            %Continue creating the app's Object/Class properties:
%             app.Paths = PathsClass( string(currentDir) );
            app.Paths = PathsClass( string(currentDir) ,"search");
%             app.Paths = PathsClass( "C:\Users\tarama\OneDrive - Intel Corporation\Desktop\לימודים\סמסטר ח'\פרויקט ב\ImageProcessing-AirwayMicroscopy-Code\App" ,"given", "C:\Users\tarama\OneDrive - Intel Corporation\Desktop\לימודים\סמסטר ח'\פרויקט ב\Data");
            app.WindowsManager = WindowsManagerClass(app ,"app") ; % Manges and controls all open apps and windows
            app.ImagesManager  = ImagesManagerClass(app.WindowsManager , app.ApearanceDefaultVals ) ;
            app.SegmentAlgo    = SegmentationAlgoClass(app.ImagesManager , app.WindowsManager); 
            app.ImagesManager.SegmentAlgo = app.SegmentAlgo; % let ImagesManager know about SegmentAlgo
            app.WindowsManager.Paths = app.Paths;
            app.EmbeddedProgressBar = EmbeddedProgressBar(app); %#ok<ADPROP> 
            
            % Set App default values at loading:
            app.set_default_values();
            app.WindowsManager.set("Image2Show_color" , "Colored");
            
            % set tree paths:
            app.set_FileTree()
            app.set("recording","stop");
            
            %Take care of images:            
            app.read_user_chosen_image();
        end

        % Callback function: ImageWindowButton, 
        % OpenSeperateImageWindowMenu
        function ImageWindowButtonValueChanged(app, event)
            app.WindowsManager.show_image_window();
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            try
                app.WindowsManager.close("MainApp");
            catch
                delete(app);
            end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            if app.ResolutionEditField.Value ~= 100
                title   = "Crop";
                message = "Crop image only at 100 Resolution";
                figureHandle = app.MainWindow;
                uialert(figureHandle,message,title);
                return
            end
            
            persistent is_waiting_for_second_press  roi
            if isempty(is_waiting_for_second_press)
                is_waiting_for_second_press = false;
                roi = [];
            end
            
            if is_waiting_for_second_press %Do the actuall Cropping:
                %Button visual as ready:
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.DefaultButtonColor;
                %Crop:
                app.ImagesManager.crop(roi)
                % clear previous masks (ROI's) from the algo-class:
                app.SegmentAlgo.clear_masks();
                %Reset for next crop attempts:
                is_waiting_for_second_press = false;
                delete(roi);
            else
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.ActiveButtonColor;
                roi = drawrectangle(app.UIAxes);               
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.WaitingButtonColor;
                is_waiting_for_second_press = true;
            end
            
        end

        % Value changed function: ImageFromlist
        function ImageFromlistValueChanged(app, event)
            app.read_user_chosen_image();
        end

        % Button pushed function: AddManuallyButton
        function AddManuallyButtonPushed(app, event)
            app.AddManuallyButton.BackgroundColor = app.ApearanceDefaultVals.ActiveButtonColor;
            roi = drawrectangle(app.UIAxes); 
            Mask = roi.createMask();
            %save results:
            app.SegmentAlgo.add_mask( Mask );
            delete(roi);
            app.AddManuallyButton.BackgroundColor = app.ApearanceDefaultVals.DefaultButtonColor;
            % Update image to show   with the new roi:
            app.ImagesManager.mask_over_image(Mask);
            app.ImagesManager.show_image();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.SegmentAlgo.set("AlgorithmFunction" , selectedTab.Title)
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodButtonGroup.SelectedObject;
            app.SegmentAlgo.set("MatlabFunctionMethod" , selectedButton.Text)
        end

        % Value changed function: SmoothFactorEditField
        function SmoothFactorEditFieldValueChanged(app, event)
            app.SegmentAlgo.set("SmoothFactor" , app.SmoothFactorEditField.Value)
        end

        % Value changed function: MaxIterationsEditField
        function MaxIterationsEditFieldValueChanged(app, event)
            value = app.MaxIterationsEditField.Value;
            app.SegmentAlgo.set("MatlabBuiltIn MaxIterations" , value)
        end

        % Value changed function: ContractionBiasEditField
        function ContractionBiasEditFieldValueChanged(app, event)
            app.SegmentAlgo.set("ContractionBias" , app.ContractionBiasEditField.Value)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.SegmentAlgo.start_or_stop_algorithm();
        end

        % Button pushed function: ChoosefromfileButton
        function ChoosefromfileButtonPushed(app, event)
            [file,path] = uigetfile( app.Paths.AllDirectories.DataDir + filesep + "*.*" ,...
                        'Select an Image File');        
            if isnumeric(file) && file==0  %user cancled
                return
            else
                Im = imread(fullfile(path,file));
                app.ImagesManager.set_original_image(Im)
            end 
            
        end

        % Button pushed function: RemoveAllButton
        function RemoveAllButtonPushed(app, event)
            app.SegmentAlgo.clear_masks();
            app.ImagesManager.clear_masks();
        end

        % Button pushed function: AddAutoButton
        function AddAutoButtonPushed(app, event)
            % act as if we chose Adaptive 
            app.TabGroup.SelectedTab = app.AdaptiveThresholdTab;
            app.SegmentAlgo.set("AlgorithmFunction" , app.AdaptiveThresholdTab.Title)
            % act as if we pressed start:
            app.StartButtonPushed();
        end

        % Value changed function: HistogramEqualizationCheckBox
        function HistogramEqualizationCheckBoxValueChanged(app, event)
            isCheckedHistEq = app.HistogramEqualizationCheckBox.Value;            
            if isCheckedHistEq
                app.ImagesManager.set("histeq_image" , "on");
                app.ImagesManager.set("Image2Show_color" , "Gray"); 
                app.WindowsManager.set("Image2Show_color" , "Gray");
            else
                app.ImagesManager.set("histeq_image" , "off");
                app.ImagesManager.set("Image2Show_color" , "Colored");
                app.WindowsManager.set("Image2Show_color" , "Colored");
            end
            % act according to config:
            app.ImagesManager.update_images_from_OriginalImage();
            app.ImagesManager.show_image();
        end

        % Button pushed function: ShrinkAllButton
        function ShrinkAllButtonPushed(app, event)
            TotallMask = app.SegmentAlgo.shrink_masks();
            app.ImagesManager.mask_over_image( TotallMask  , "FromScratch" );
            app.ImagesManager.show_image();
        end

        % Value changed function: ResolutionSlider
        function ResolutionSliderValueChanged(app, event)
            value = app.ResolutionSlider.Value;
            app.update_resolution(value);
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            app.update_resolution(value);            
        end

        % Button pushed function: RemoveManuallyButton
        function RemoveManuallyButtonPushed(app, event)
            if     (app.pressOnScreenState == "idle") 
                newState = "waiting_to_remove";    
                app.RemoveManuallyButton.BackgroundColor = app.ApearanceDefaultVals.ActiveButtonColor ;
            elseif (app.pressOnScreenState == "working")
                warning("We are already working on it ???");
                newState = "working";
            elseif (app.pressOnScreenState == "waiting_to_remove")
                newState = "idle";
                app.RemoveManuallyButton.BackgroundColor = app.ApearanceDefaultVals.DefaultButtonColor;
            else
                error("incorrect option");
            end
            
            app.pressOnScreenState = newState;
        end

        % Window button down function: MainWindow
        function MainWindowWindowButtonDown(app, event)
            if (app.pressOnScreenState == "idle") || (app.pressOnScreenState == "working")
                return
            elseif (app.pressOnScreenState == "waiting_to_remove")
                % Continue:
            else
                error("incorrect option");
            end
            
            try
                Type = event.Source.CurrentObject.Type;
            catch 
                warning("Wrong object 'Type' ");
                return
            end
            
            if string(Type) == "image"
                % all good. continue...
            else % ~= image:
                warning("None-Image Type");
                return
            end
            
            % Get x and y:
            location = app.UIAxes.CurrentPoint;
            location = location(1 , [1:2]);
            
            % find and delete mask:
            mask_index = app.SegmentAlgo.find_mask(location);
            if isempty(mask_index) % we didn't find
                disp("We didn't find the mask");
                return
            else
                app.SegmentAlgo.remove_mask(mask_index);    
                app.SegmentAlgo.replot_all_masks()
            end
            
        end

        % Value changed function: ImageTypeDropDown_3, 
        % ImageTypeDropDown_4, ImageTypeDropDown_5
        function ImageTypeDropDownValueChanged(app, event)
            value = event.Source.Value;
            app.set("ImageType" , value );
        end

        % Value changed function: IterationsPerFrameEditField
        function IterationsPerFrameEditFieldValueChanged(app, event)
            value = app.IterationsPerFrameEditField.Value;
            app.SegmentAlgo.set("IterationsPerFrame" , value);
        end

        % Menu selected function: ColoredMenu
        function ColoredMenuSelected(app, event)
            % Let Objects know:
            app.ImagesManager.set("Image2Show_color" , "Colored"); 
            app.WindowsManager.set("Image2Show_color" , "Colored");
            % replot image:
            app.ImagesManager.update_images_from_OriginalImage();
            app.ImagesManager.show_image();
        end

        % Menu selected function: GrayMenu
        function GrayMenuSelected(app, event)
            % Let Objects know:
            app.ImagesManager.set("Image2Show_color" , "Gray");
            app.WindowsManager.set("Image2Show_color" , "Gray");
            % replot image:
            app.ImagesManager.update_images_from_OriginalImage();
            app.ImagesManager.show_image();
        end

        % Window key press function: MainWindow
        function MainWindowWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'a'
                    app.AddManuallyButtonPushed();
                case 'r'
                    app.RemoveManuallyButtonPushed();
                otherwise
                    % Do Nothing
            end
        end

        % Menu selected function: CreditsMenu
        function CreditsMenuSelected(app, event)
            message =  credits_string();
            title = "Credits:";
            msgbox(message,title);
        end

        % Value changed function: WindowSizeEditField
        function WindowSizeEditFieldValueChanged(app, event)
            value = app.WindowSizeEditField.Value;
            app.SegmentAlgo.set("Adaptive WindowSize" , value);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.SegmentAlgo.set("Adaptive Threshold" , value);
        end

        % Value changed function: DiskRadiusEditField
        function DiskRadiusEditFieldValueChanged(app, event)
            value = app.DiskRadiusEditField.Value;
            app.SegmentAlgo.set("Adaptive DiskRadius" , value);
        end

        % Selection changed function: MeanorMedianButtonGroup
        function MeanorMedianButtonGroupSelectionChanged(app, event)
            selectedButton = app.MeanorMedianButtonGroup.SelectedObject;
            if     string(selectedButton.Text) == "Median"
                value = 1;
            elseif string(selectedButton.Text) == "Mean"
                value = 0;
            else
                error("No such option");
            end
            app.SegmentAlgo.set("Adaptive MedianOrMean" , value);
        end

        % Value changed function: UpperThresholdEditField
        function UpperThresholdEditFieldValueChanged(app, event)
            value = app.UpperThresholdEditField.Value;
            app.SegmentAlgo.set("Adaptive SolidityUpperThreshold" , value);
        end

        % Value changed function: LowerThresholdEditField
        function LowerThresholdEditFieldValueChanged(app, event)
            value = app.LowerThresholdEditField.Value;
            app.SegmentAlgo.set("Adaptive SolidityLowerThreshold" , value);
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            value = app.ToleranceEditField.Value;            
            app.SegmentAlgo.set("WaterShed Tolerance" , value);
        end

        % Value changed function: BlurringCheckBox
        function BlurringCheckBoxValueChanged(app, event)
            isBlurring = app.BlurringCheckBox.Value;
            if isBlurring
                %%
                title = "Blurring Options";
                prompt   = ["Window Size" , "Sigma"        ];
                definput = [ string(15)   ,   string(10)    ];
                dims     = [  [1.5 ,8 ]   ;   [1.5,8]       ];
                answer = inputdlg(prompt,title,dims,definput);
                if isempty(answer) % user canceled
                    app.BlurringCheckBox.Value = false;
                    return
                else
                    options = struct();
                    options.WindowSize = str2double(answer{1});
                    options.Sigma      = str2double(answer{2});
                end
                app.ImagesManager.set("blurring" , "on" , "options" ,options);                
            else
                app.ImagesManager.set("blurring" , "off");
            end
            % act according to config:
            app.ImagesManager.update_images_from_OriginalImage();
            app.ImagesManager.show_image();
        end

        % Button pushed function: FromFileButton
        function FromFileButtonPushed(app, event)
            % Load mask file:
            defaultFullPath = app.Paths.Results.TempResults.tempMask_FullPath;
            title = "Choose a Mask:";
            [file,path] = uigetfile(defaultFullPath,title);
            if isnumeric(file) && file==0  %user canceled:
                return
            end
            LoadedVariable = load(fullfile(path,file));
            % Get Mask Matrirx:
            Fields = fields( LoadedVariable );
            AllMask = LoadedVariable.(Fields{1});
            % Load to segmentation algo as parts:
            CC = bwconncomp(AllMask);
            for i = 1 : CC.NumObjects
                PixelIdxList = CC.PixelIdxList{i};
                Mask = false(CC.ImageSize);
                Mask(PixelIdxList) = true;
                %add mask
                app.SegmentAlgo.add_mask(Mask);                
            end
            % show masks:
            app.SegmentAlgo.replot_all_masks();
        end

        % Selection changed function: FileTree
        function FileTreeSelectionChanged(app, event)
            selectedNodes = app.FileTree.SelectedNodes;
            if selectedNodes.Tag == "ImageTreeNode"
                ImagePath = selectedNodes.NodeData.Path;                
                Im = imread(ImagePath);
                app.ImagesManager.set_original_image(Im);    
                
                ImageType = selectedNodes.NodeData.Type;
                app.set("ImageType" , ImageType);
            end
        end

        % Menu selected function: StartRecordingMenu
        function StartRecordingMenuSelected(app, event)
            app.set("recording","start");
            app.WindowsManager.set("recording","start");
        end

        % Menu selected function: PauseRecordingMenu
        function PauseRecordingMenuSelected(app, event)
            app.set("recording","pause");
            app.WindowsManager.set("recording","pause");
        end

        % Menu selected function: StopRecordingMenu
        function StopRecordingMenuSelected(app, event)
            app.set("recording","stop");
            app.WindowsManager.set("recording","stop");
        end

        % Value changed function: MasksRecombinationCheckBox
        function MasksRecombinationCheckBoxValueChanged(app, event)
            value = app.MasksRecombinationCheckBox.Value;
            app.SegmentAlgo.set("MatlabBuildIn MasksRecombination" , value)
        end

        % Value changed function: 
        % BackgroundSubstractionRadiusEditField
        function BackgroundSubstractionRadiusEditFieldValueChanged(app, event)
            value = app.BackgroundSubstractionRadiusEditField.Value;
            % set image Config:
            app.ImagesManager.set("BackgroundSubstractionRadius" , value);
            % act according to config:
            app.ImagesManager.update_images_from_OriginalImage();
            app.ImagesManager.show_image();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Active Contours App ';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.MainWindow.WindowButtonDownFcn = createCallbackFcn(app, @MainWindowWindowButtonDown, true);
            app.MainWindow.WindowKeyPressFcn = createCallbackFcn(app, @MainWindowWindowKeyPress, true);
            app.ViewMenu = uimenu(app.MainWindow);
            app.ViewMenu.Text = 'View';
            app.ImageToShowMenu = uimenu(app.ViewMenu);
            app.ImageToShowMenu.Separator = 'on';
            app.ImageToShowMenu.Text = 'Image To Show';
            app.ColoredMenu = uimenu(app.ImageToShowMenu);
            app.ColoredMenu.MenuSelectedFcn = createCallbackFcn(app, @ColoredMenuSelected, true);
            app.ColoredMenu.Text = 'Colored';
            app.GrayMenu = uimenu(app.ImageToShowMenu);
            app.GrayMenu.MenuSelectedFcn = createCallbackFcn(app, @GrayMenuSelected, true);
            app.GrayMenu.Text = 'Gray';
            app.OpenSeperateImageWindowMenu = uimenu(app.ViewMenu);
            app.OpenSeperateImageWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageWindowButtonValueChanged, true);
            app.OpenSeperateImageWindowMenu.Text = 'Open Seperate Image Window';
            app.RecordMenu = uimenu(app.MainWindow);
            app.RecordMenu.Text = 'Record';
            app.StartRecordingMenu = uimenu(app.RecordMenu);
            app.StartRecordingMenu.MenuSelectedFcn = createCallbackFcn(app, @StartRecordingMenuSelected, true);
            app.StartRecordingMenu.Text = 'Start Recording';
            app.PauseRecordingMenu = uimenu(app.RecordMenu);
            app.PauseRecordingMenu.MenuSelectedFcn = createCallbackFcn(app, @PauseRecordingMenuSelected, true);
            app.PauseRecordingMenu.Text = 'Pause Recording';
            app.StopRecordingMenu = uimenu(app.RecordMenu);
            app.StopRecordingMenu.MenuSelectedFcn = createCallbackFcn(app, @StopRecordingMenuSelected, true);
            app.StopRecordingMenu.Text = 'Stop Recording';
            app.HelpMenu = uimenu(app.MainWindow);
            app.HelpMenu.Text = 'Help';
            app.CreditsMenu = uimenu(app.HelpMenu);
            app.CreditsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreditsMenuSelected, true);
            app.CreditsMenu.Text = 'Credits';
            app.UIAxes = uiaxes(app.MainWindow);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ImageWindowButton = uibutton(app.MainWindow, 'state');
            app.ImageWindowButton.ValueChangedFcn = createCallbackFcn(app, @ImageWindowButtonValueChanged, true);
            app.ImageWindowButton.Text = 'Image Window';
            app.StartButton = uibutton(app.MainWindow, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ProgressBarAxes = uiaxes(app.MainWindow);
            title(app.ProgressBarAxes, 'Title')
            xlabel(app.ProgressBarAxes, 'X')
            ylabel(app.ProgressBarAxes, 'Y')
            app.ProgressBarText = uilabel(app.MainWindow);
            app.ProgressBarText.Text = 'progressLabel[%]';
            app.MaskCoverPercentageLabel = uilabel(app.MainWindow);
            app.MaskCoverPercentageLabel.Text = 'Mask Cover Percentage';
            app.MaskCoverPercentageEditField = uieditfield(app.MainWindow, 'numeric');
            app.MaskCoverPercentageEditField.Editable = 'off';
            app.QuestionMark = uilabel(app.MainWindow);
            app.QuestionMark.Text = '?';
            app.Label = uilabel(app.MainWindow);
            app.Label.Text = '[%]';
            app.TabGroup2 = uitabgroup(app.MainWindow);
            app.ChooseImageTab = uitab(app.TabGroup2);
            app.ChooseImageTab.Title = 'Choose Image';
            app.ImageFromlistListBoxLabel = uilabel(app.ChooseImageTab);
            app.ImageFromlistListBoxLabel.Text = 'Image (From list)';
            app.ImageFromlist = uilistbox(app.ChooseImageTab);
            app.ImageFromlist.ValueChangedFcn = createCallbackFcn(app, @ImageFromlistValueChanged, true);
            app.ChoosefromfileButton = uibutton(app.ChooseImageTab, 'push');
            app.ChoosefromfileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefromfileButtonPushed, true);
            app.ChoosefromfileButton.Text = 'Choose from file';
            app.FileTree = uitree(app.ChooseImageTab);
            app.FileTree.SelectionChangedFcn = createCallbackFcn(app, @FileTreeSelectionChanged, true);
            app.ImageTypeDropDown_4Label = uilabel(app.ChooseImageTab);
            app.ImageTypeDropDown_4Label.Text = 'Image Type:';
            app.ImageTypeDropDown_4 = uidropdown(app.ChooseImageTab);
            app.ImageTypeDropDown_4.Items = {'Unknown', 'Red', 'Natural', 'Flask', 'Coating'};
            app.ImageTypeDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.ImageTypeDropDown_4.Value = 'Unknown';
            app.ImageManipulationsTab = uitab(app.TabGroup2);
            app.ImageManipulationsTab.Title = 'Image Manipulations';
            app.HistogramEqualizationCheckBox = uicheckbox(app.ImageManipulationsTab);
            app.HistogramEqualizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @HistogramEqualizationCheckBoxValueChanged, true);
            app.HistogramEqualizationCheckBox.Text = {'Histogram'; 'Equalization'};
            app.ResolutionEditField = uieditfield(app.ImageManipulationsTab, 'numeric');
            app.ResolutionEditField.Limits = [0 100];
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 100;
            app.BlurringCheckBox = uicheckbox(app.ImageManipulationsTab);
            app.BlurringCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlurringCheckBoxValueChanged, true);
            app.BlurringCheckBox.Text = 'Blurring';
            app.ResolutionLabel = uilabel(app.ImageManipulationsTab);
            app.ResolutionLabel.Text = '  Resolution ';
            app.ResolutionSlider = uislider(app.ImageManipulationsTab);
            app.ResolutionSlider.MajorTicks = [0 20 40 60 80 100];
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionSliderValueChanged, true);
            app.ResolutionSlider.Value = 100;
            app.CropButton = uibutton(app.ImageManipulationsTab, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ImageTypeDropDown_3Label = uilabel(app.ImageManipulationsTab);
            app.ImageTypeDropDown_3Label.Text = 'Image Type:';
            app.ImageTypeDropDown_3 = uidropdown(app.ImageManipulationsTab);
            app.ImageTypeDropDown_3.Items = {'Unknown', 'Red', 'Natural', 'Flask', 'Coating'};
            app.ImageTypeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.ImageTypeDropDown_3.Value = 'Unknown';
            app.BackgroundSubstractionRadiusEditFieldLabel = uilabel(app.ImageManipulationsTab);
            app.BackgroundSubstractionRadiusEditFieldLabel.Text = 'Background Substraction Radius';
            app.BackgroundSubstractionRadiusEditField = uieditfield(app.ImageManipulationsTab, 'numeric');
            app.BackgroundSubstractionRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @BackgroundSubstractionRadiusEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.ImageManipulationsTab);
            app.Label_2.Text = '[%]';
            app.ImageSegmentationTab = uitab(app.TabGroup2);
            app.ImageSegmentationTab.Title = 'Image Segmentation';
            app.TabGroup = uitabgroup(app.ImageSegmentationTab);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MATLABsSnakesTab = uitab(app.TabGroup);
            app.MATLABsSnakesTab.Title = 'MATLAB''s Snakes';
            app.MethodButtonGroup = uibuttongroup(app.MATLABsSnakesTab);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.RegionButton = uiradiobutton(app.MethodButtonGroup);
            app.RegionButton.Text = 'Region';
            app.RegionButton.Value = true;
            app.EdgeButton = uiradiobutton(app.MethodButtonGroup);
            app.EdgeButton.Text = 'Edge';
            app.SmoothFactorEditFieldLabel = uilabel(app.MATLABsSnakesTab);
            app.SmoothFactorEditFieldLabel.Text = 'Smooth Factor';
            app.SmoothFactorEditField = uieditfield(app.MATLABsSnakesTab, 'numeric');
            app.SmoothFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothFactorEditFieldValueChanged, true);
            app.SmoothFactorEditField.Value = 4;
            app.ContractionBiasEditFieldLabel = uilabel(app.MATLABsSnakesTab);
            app.ContractionBiasEditFieldLabel.Text = 'Contraction Bias';
            app.ContractionBiasEditField = uieditfield(app.MATLABsSnakesTab, 'numeric');
            app.ContractionBiasEditField.ValueChangedFcn = createCallbackFcn(app, @ContractionBiasEditFieldValueChanged, true);
            app.ContractionBiasEditField.Value = 0.01;
            app.MaxIterationsEditFieldLabel = uilabel(app.MATLABsSnakesTab);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.MATLABsSnakesTab, 'numeric');
            app.MaxIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIterationsEditFieldValueChanged, true);
            app.MaxIterationsEditField.Value = 1000;
            app.IterationsPerFrameEditFieldLabel = uilabel(app.MATLABsSnakesTab);
            app.IterationsPerFrameEditFieldLabel.Text = 'Iterations Per Frame';
            app.IterationsPerFrameEditField = uieditfield(app.MATLABsSnakesTab, 'numeric');
            app.IterationsPerFrameEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsPerFrameEditFieldValueChanged, true);
            app.IterationsPerFrameEditField.Value = 1;
            app.MasksRecombinationCheckBox = uicheckbox(app.MATLABsSnakesTab);
            app.MasksRecombinationCheckBox.ValueChangedFcn = createCallbackFcn(app, @MasksRecombinationCheckBoxValueChanged, true);
            app.MasksRecombinationCheckBox.Text = 'Masks Recombination';
            app.WatershedTab = uitab(app.TabGroup);
            app.WatershedTab.Title = 'Watershed';
            app.ToleranceEditFieldLabel = uilabel(app.WatershedTab);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.WatershedTab, 'numeric');
            app.ToleranceEditField.Limits = [0 100];
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 1;
            app.AdaptiveThresholdTab = uitab(app.TabGroup);
            app.AdaptiveThresholdTab.Title = 'Adaptive Threshold';
            app.WindowSizeEditFieldLabel = uilabel(app.AdaptiveThresholdTab);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.AdaptiveThresholdTab, 'numeric');
            app.WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeEditFieldValueChanged, true);
            app.WindowSizeEditField.Value = 3;
            app.ThresholdEditFieldLabel = uilabel(app.AdaptiveThresholdTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.AdaptiveThresholdTab, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.01;
            app.DiskRadiusEditFieldLabel = uilabel(app.AdaptiveThresholdTab);
            app.DiskRadiusEditFieldLabel.Text = 'Disk Radius';
            app.DiskRadiusEditField = uieditfield(app.AdaptiveThresholdTab, 'numeric');
            app.DiskRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @DiskRadiusEditFieldValueChanged, true);
            app.DiskRadiusEditField.Value = 0.01;
            app.UpperThresholdEditFieldLabel = uilabel(app.AdaptiveThresholdTab);
            app.UpperThresholdEditFieldLabel.Text = 'Upper Threshold';
            app.UpperThresholdEditField = uieditfield(app.AdaptiveThresholdTab, 'numeric');
            app.UpperThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @UpperThresholdEditFieldValueChanged, true);
            app.UpperThresholdEditField.Value = 0.01;
            app.LowerThresholdEditField_2Label = uilabel(app.AdaptiveThresholdTab);
            app.LowerThresholdEditField_2Label.Text = 'Lower Threshold';
            app.LowerThresholdEditField = uieditfield(app.AdaptiveThresholdTab, 'numeric');
            app.LowerThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @LowerThresholdEditFieldValueChanged, true);
            app.LowerThresholdEditField.Value = 0.01;
            app.SolidityFilterLabel = uilabel(app.AdaptiveThresholdTab);
            app.SolidityFilterLabel.Text = 'Solidity Filter:';
            app.MeanorMedianButtonGroup = uibuttongroup(app.AdaptiveThresholdTab);
            app.MeanorMedianButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MeanorMedianButtonGroupSelectionChanged, true);
            app.MeanorMedianButtonGroup.Title = 'Mean or Median';
            app.MeanButton = uiradiobutton(app.MeanorMedianButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.MedianButton = uiradiobutton(app.MeanorMedianButtonGroup);
            app.MedianButton.Text = 'Median';
            app.CannyThresholdingFusionTab = uitab(app.TabGroup);
            app.CannyThresholdingFusionTab.Title = 'Canny Thresholding Fusion';
            app.ROIPanel = uipanel(app.ImageSegmentationTab);
            app.ROIPanel.Title = 'ROI';
            app.AddManuallyButton = uibutton(app.ROIPanel, 'push');
            app.AddManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @AddManuallyButtonPushed, true);
            app.AddManuallyButton.Text = 'Add Manually';
            app.AddAutoButton = uibutton(app.ROIPanel, 'push');
            app.AddAutoButton.ButtonPushedFcn = createCallbackFcn(app, @AddAutoButtonPushed, true);
            app.AddAutoButton.Tooltip = {'Using Adaptive Threshold'};
            app.AddAutoButton.Text = 'Add Auto';
            app.RemoveAllButton = uibutton(app.ROIPanel, 'push');
            app.RemoveAllButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllButtonPushed, true);
            app.RemoveAllButton.Text = 'Remove All';
            app.ShrinkAllButton = uibutton(app.ROIPanel, 'push');
            app.ShrinkAllButton.ButtonPushedFcn = createCallbackFcn(app, @ShrinkAllButtonPushed, true);
            app.ShrinkAllButton.Text = 'Shrink All';
            app.RemoveManuallyButton = uibutton(app.ROIPanel, 'push');
            app.RemoveManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveManuallyButtonPushed, true);
            app.RemoveManuallyButton.Text = 'Remove Manually';
            app.FromFileButton = uibutton(app.ROIPanel, 'push');
            app.FromFileButton.ButtonPushedFcn = createCallbackFcn(app, @FromFileButtonPushed, true);
            app.FromFileButton.Text = 'From File';
            app.ImageTypeDropDown_5Label = uilabel(app.ImageSegmentationTab);
            app.ImageTypeDropDown_5Label.Text = 'Image Type:';
            app.ImageTypeDropDown_5 = uidropdown(app.ImageSegmentationTab);
            app.ImageTypeDropDown_5.Items = {'Unknown', 'Red', 'Natural', 'Flask', 'Coating'};
            app.ImageTypeDropDown_5.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.ImageTypeDropDown_5.Value = 'Unknown';
            app.RecordingLampLabel = uilabel(app.MainWindow);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.MainWindow);
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ActiveContoursApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end