classdef ImageWindowApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageWindow  matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        WindowsManager % handle to manager of all open windwos
    end
    
    methods (Access = public)
        
          function [] = update_image2show(app , image2show)
            imshow( image2show , 'Parent' , app.UIAxes);
            app.UIAxes.XLabel.Visible = "off";
            app.UIAxes.YLabel.Visible = "off";
            app.UIAxes.Title.Visible  = "off";
          end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, WindowsManager)
            app.WindowsManager = WindowsManager;
        end

        % Close request function: ImageWindow
        function ImageWindowCloseRequest(app, event)
            app.WindowsManager.close('ImageWindow');
            %delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageWindow = uifigure('Visible', 'off');
            app.ImageWindow.Name = 'UI Figure';
            app.ImageWindow.CloseRequestFcn = createCallbackFcn(app, @ImageWindowCloseRequest, true);
            app.UIAxes = uiaxes(app.ImageWindow);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.ImageWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageWindowApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageWindow)
        end
    end
end